classdef DTMF_FinalHW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AboutMenu                 matlab.ui.container.Menu
        AuthorsnameMenu           matlab.ui.container.Menu
        studentsIDPB19010390Menu  matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        Menu_26                   matlab.ui.container.Menu
        Menu_27                   matlab.ui.container.Menu
        DTMFMenu                  matlab.ui.container.Menu
        Menu                      matlab.ui.container.Menu
        Menu_4                    matlab.ui.container.Menu
        Menu_6                    matlab.ui.container.Menu
        Menu_7                    matlab.ui.container.Menu
        Menu_8                    matlab.ui.container.Menu
        Menu_2                    matlab.ui.container.Menu
        Menu_5                    matlab.ui.container.Menu
        Menu_3                    matlab.ui.container.Menu
        informationmatMenu        matlab.ui.container.Menu
        Menu_9                    matlab.ui.container.Menu
        Menu_10                   matlab.ui.container.Menu
        Menu_11                   matlab.ui.container.Menu
        Menu_12                   matlab.ui.container.Menu
        DTMFMenu_2                matlab.ui.container.Menu
        Menu_13                   matlab.ui.container.Menu
        Menu_17                   matlab.ui.container.Menu
        Menu_18                   matlab.ui.container.Menu
        Menu_16                   matlab.ui.container.Menu
        Menu_19                   matlab.ui.container.Menu
        Menu_20                   matlab.ui.container.Menu
        Menu_21                   matlab.ui.container.Menu
        Menu_22                   matlab.ui.container.Menu
        Menu_23                   matlab.ui.container.Menu
        Menu_24                   matlab.ui.container.Menu
        Menu_25                   matlab.ui.container.Menu
        Menu_14                   matlab.ui.container.Menu
        Menu_15                   matlab.ui.container.Menu
        DTMFLabel_2               matlab.ui.control.Label
        DTMFLabel                 matlab.ui.control.Label
        EditField_4               matlab.ui.control.EditField
        EditField_4Label          matlab.ui.control.Label
        Button_19                 matlab.ui.control.Button
        Button_13                 matlab.ui.control.Button
        Button_16                 matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
        EditFieldLabel            matlab.ui.control.Label
        EditField_3               matlab.ui.control.EditField
        EditField_3Label          matlab.ui.control.Label
        Button_14                 matlab.ui.control.Button
        Button_18                 matlab.ui.control.Button
        Button_17                 matlab.ui.control.Button
        Button_15                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        Button_11                 matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        DButton                   matlab.ui.control.Button
        CButton                   matlab.ui.control.Button
        BButton                   matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        AButton                   matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        msSlider                  matlab.ui.control.Slider
        msLabel                   matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        EditField_2Label          matlab.ui.control.Label
        EditField_5               matlab.ui.control.EditField
        EditField_5Label          matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)%全局变量区
        str1='1';str2='2';str3='3';str4='4';str5='5';str6='6';
        str7='7';str8='8';str9='9';str0='0';
        str10='*';str11='#';
        strA='A';strB='B';strC='C';strD='D';
        F;LENT;SIG;
    end
    
    methods (Access = private)
        %画出单独的波形
        function results = signal(app,character,stt,r)
            %character是拨号字符,stt是信号起始时间点，最后的整个信号是
            % 将各种单信号进行连接,r是静音间隔
            switch character
                case '1'
                    f1=697;f2=1209;%f1是低频，f2是高频
                case '2'
                    f1=697;f2=1336;
                case '3'
                    f1=697;f2=1477;
                case '4'
                    f1=770;f2=1209;
                case '5'
                    f1=770;f2=1336;
                case '6'
                    f1=770;f2=1477;
                case '7'
                    f1=852;f2=1336;
                case '8'
                    f1=852;f2=1336;
                case '9'
                    f1=852;f2=1477;
                case '0'
                    f1=941;f2=1336;
                case 'A'
                    f1=697;f2=1633;
                case 'B'
                    f1=770;f2=1633;
                case 'C'
                    f1=852;f2=1633;
                case 'D'
                    f1=941;f2=1633;
                case '*'
                    f1=941;f2=1209;
                case '#'
                    f1=941;f2=1477;
            end
            fs = app.EditField_2.Value;%取得采样频率
            lent=app.msSlider.Value;%取得采样时间
            t = stt:1/fs:stt+lent*1e-3+r;%lent单位是毫秒，应该转化成秒
            results=(sin(2*pi*f1*(t-stt)) + sin(2*pi*f2*(t-stt))).*((t>stt)&(t<stt+lent*1e-3));
           %这里乘上一个逻辑判断的系数目的是为了在静音间隔中画出全0的信号
            end
        %判断是否合法
            function results = judge(app,character)
            if(character~='1'&character~='2'&character~='3'&character~='4'&character~='5' ...
              &character~='6'&character~='7'&character~='8'&character~='9'&character~='0' ...
              &character~='A'&character~='B'&character~='C'&character~='D'&character~='*' ...
              &character~='#')
               results=0;
            else results=1;
           
            end
        end
        %画出合成波形并返还波形内蕴数据
        function [siggen,t] = draw(app)
            value = convertStringsToChars(app.EditField_5.Value);
            %convert是强制转化为字符数组
            N=size(value);%N是一行两列
            lent1=app.msSlider.Value;%采样时间
            fs1=app.EditField_2.Value;%采样频率
            ran=0.098*rand(1,N(2))+0.002;%生成随机长度的静音间隔
            t=0:1/fs1:N(2)*lent1*1e-3+sum(ran);
            siggen=[];%产生空数组用于连接，程序运行完的siggen即为波形
            adds=0;%记录要绘制的信号的起点
            for k=1:N(2)
                s=signal(app,value(k),(k-1)*lent1*1e-3+adds,ran(k));
                siggen=cat(2,siggen,s);%与刚绘制的信号进行连接，2，表示数组横向连接
                adds=adds+ran(k);
            end
            axis(app.UIAxes,[0,t(end),min(siggen),max(siggen)*1.1]);
            M=size(t);
            plot(app.UIAxes,t,siggen(1:M(2))); %M(2)用来使两个数组长度一致
        end
        %通过读取一组估计频率值来计算DTMF的合成频率
        function [f1,f2] = testlit(app,fa,fb)
            faacc=[697 770 852 941];%acc是accurate
            fbacc=[1209 1336 1477 1633];
            a=[abs(fa-faacc(1))+1,abs(fa-faacc(2))+1,abs(fa-faacc(3))+1,abs(fa-faacc(4))+1];
            b=[abs(fb-fbacc(1))+1,abs(fb-fbacc(2))+1,abs(fb-fbacc(3))+1,abs(fb-fbacc(4))+1];
            %加一是因为find函数不能寻找为0的数组下标，但是加一不影响数值先后
            ind1=find(a==min(a));
            ind2=find(b==min(b));%n是数组位置
            f1=faacc(ind1);
            f2=fbacc(ind2);
        end
        %利用程序内生成的波形给出两种频率估计值,同时生成幅度谱
        function [fresult,f1rest,f2rest]= estresul(app,result,fs,lent)
            %estresul是estimate result的缩写，result是给出的不含静音间隔
            %的一段波形来给出两个叠加频率的估计值本函数在获取准确的DTMF频率
            %同时也返回幅度谱波形数值利于绘制幅度谱
            fresult=abs(fft(result));%进行傅里叶变换得幅度谱fresult
            [pks,locs]=findpeaks(fresult(1:end/2));%获取峰值
            N=lent*fs*1e-3;
            n1=locs(1);
            n2=locs(2);
            f1est=n1/N*fs;
            f2est=n2/N*fs;%两个估计值(不一定在DTMF给出的数值中)
            [f1rest,f2rest]=testlit(app,f1est,f2est);%获得DTMF频率值
            end 
        %检测当前程序的字符串
            function strest=test1(app)
            strest=[];    
            fs=app.EditField_2.Value;
            lent=app.msSlider.Value;
            [siggen,t]=draw(app);
            app.SIG=siggen;%获得全局变量
            app.F=fs;
            app.LENT=lent;
            axis(app.UIAxes2,[0,t(end),min(siggen),max(siggen)*1.1]);
            M=size(t);
            plot(app.UIAxes2,t,siggen(1:M(2)));%检测时画出时域波形图
            a(1)=0;%数组a,b用来从含有静音间隔的信号得到有效波形的端点
            m=2,n=1;
            for k=2:M(2)-2%防止数组溢出
                if(siggen(k)==0&siggen(k+1)==0&siggen(k-1)~=0)
                    b(n)=t(k);
                    n=n+1;%b是有效波形的右端横坐标
                end
                if(siggen(k)==0&siggen(k-1)==0&siggen(k+1)~=0)
                    a(m)=t(k);%a是有效波形的左端横坐标
                    m=m+1;
                end
            end
            %最后的结果一定会有m==n
            %对每一段有效波形进行分析并得到相应波形对应的号码
            for l=1:m-1
                [fresult,f1rest,f2rest]=estresul(app,siggen(floor(a(l)*fs)+1:floor(b(l)*fs)),fs,lent);
                c=tellstr(app,f1rest,f2rest);
                strest=cat(2,strest,c);%连字成串
            end
            strest=convertCharsToStrings(strest);%显示区只能给出字符串而不是字符数组
        end
        %检测导入的wav文件
        %导入wav文件需要点击按钮，此时将会把波形内容存入全局变量
        function strest=test2(app,siggen,t,fs,lent)
            strest=[]; 
            M=size(t);
            a(1)=0;
            m=2,n=1;
            for k=2:M(2)-2%防止数组溢出
                if(siggen(k)==0&siggen(k+1)==0&siggen(k-1)~=0)
                    b(n)=t(k);
                    n=n+1;%b是有效波形的右端横坐标
                end
                if(siggen(k)==0&siggen(k-1)==0&siggen(k+1)~=0)
                    a(m)=t(k);
                    m=m+1;
                end
            end
            for l=1:m-1
                [fresult,f1rest,f2rest]=estresul(app,siggen(floor(a(l)*fs)+1:floor(b(l)*fs)),fs,lent);
                c=tellstr(app,f1rest,f2rest);
                strest=cat(2,strest,c);
            end
            strest=convertCharsToStrings(strest);
        end
        
        function results = tellstr(app,f1,f2)
            if(f1==697&f2==1209) results='1';
            elseif(f1==697&f2==1336) results='2';
            elseif(f1==697&f2==1477) results='3';    
            elseif(f1==697&f2==1633) results='A';       
            elseif(f1==770&f2==1209) results='4';
            elseif(f1==770&f2==1336) results='5';
            elseif(f1==770&f2==1477) results='6';
            elseif(f1==770&f2==1633) results='B';
            elseif(f1==852&f2==1209) results='7';
            elseif(f1==852&f2==1336) results='8';
            elseif(f1==852&f2==1477) results='9'; 
            elseif(f1==852&f2==1633) results='C';     
            elseif(f1==941&f2==1209) results='*';
            elseif(f1==941&f2==1336) results='0';
            elseif(f1==941&f2==1477) results='#';
            elseif(f1==941&f2==1633) results='D';
            else results='';
            end
        end
        %判断是否是空号并给出提示
        function results = ismyempty(app,value)%value是字符串
            if(value=="")
                app.EditField_3.Value="您拨打的是空号，请重输！"
                lent1=app.msSlider.Value;
                fs1= app.EditField_2.Value;
                t=0:1/fs1:lent1*1e-3;
                siggen=zeros(size(t));
                plot(app.UIAxes,t,siggen);%空号时波形显示是0
                error("空号")
            else
                app.EditField_3.Value="输入正确，已为您显示结果"
            end
        end
        %判断是否是非法字符并给出非法提示
        function results = isillegal(app,value)%value是字符数组
           N=size(value);%N是一行两列
           tag=1;
            for l=1:N(2)
                tag=judge(app,value(l))
                if(tag==0)
                    app.EditField_3.Value="非法输入，请检查后重播！"
                    error("非法输入")
                end
            end
            app.EditField_3.Value="输入正确，已为您显示结果！"
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value1= convertStringsToChars(app.EditField_5.Value);
            isillegal(app,value1);
            value2=app.EditField_5.Value;
            ismyempty(app,value2);
            draw(app)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            value=8000;%系统默认值
            value2=app.EditField_5.Value;
            ismyempty(app,value2);
            draw(app)
        end

        % Value changed function: msSlider
        function msSliderValueChanged(app, event)
            value2=app.EditField_5.Value;
            ismyempty(app,value2);
            draw(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str1));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str2));
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str3));
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.strA));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str4));
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str5));
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str6));
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.strB));
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str7));
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str8));
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str9));
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.strC));
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.strD));
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str11));
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str0));
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.EditField_5.Value=convertCharsToStrings(cat(2,convertStringsToChars(app.EditField_5.Value),app.str10));
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.EditField_5.Value="";
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            value2=app.EditField_5.Value;
            ismyempty(app,value2);
            draw(app);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            clisound=draw(app);%cli是客户client
            sound(clisound);%播放声音
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            %save的时候不允许用户保存空号！
            value2=app.EditField_5.Value;
            ismyempty(app,value2);
            [clisound,t]=draw(app);
            Fs=app.EditField_2.Value;
            length=app.msSlider.Value
            str=app.EditField_5.Value;
            client.wavdata=clisound;%存储波形
            client.time=t;%用户可以用t和clisound直接画出波形图
            client.fre=Fs;%存储采样频率
            client.mes=str;%存储拨号字符串(message)
            client.lent=length;
            save('information.mat','client');
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            value2=app.EditField_5.Value;
            ismyempty(app,value2);
            value1= convertStringsToChars(app.EditField_5.Value);
            isillegal(app,value1);
            app.EditField.Value=test1(app);%测试程序中产生的波形
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            value=app.EditField_5.Value;
            str=convertStringsToChars(value);
            G=size(str);%回退键当已经是空号的时候应给出无法回退的警告
            if(G(2)==0)
                app.EditField_3.Value="目前是空号！";
                error("空号");
            end
            str(G(2))='';%正常回退
            app.EditField_5.Value=convertCharsToStrings(str);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            [filename,pathname]=uigetfile('.mat','选择要载入的拨号文件');
            location=strcat(pathname,filename);
            data=importdata(location);%此刻data就是一个结构体数组
            siggen=data.wavdata;
            t=data.time;
            fs=data.fre;
            str=data.mes;
            lent=data.lent;
            %开始获取全局变量来应用test2检测
            app.SIG=siggen;
            app.F=fs;
            app.LENT=lent;
            str=convertCharsToStrings(str);
            app.EditField_4.Value=str;%str是字符串而不是字符向量
            axis(app.UIAxes2,[0,t(end),min(siggen),max(siggen)*1.1]);
            M=size(t);
            plot(app.UIAxes2,t,siggen(1:M(2)));%利用上传用户信息来做出时域波形
            strest=test2(app,siggen,t,fs,lent);
            app.EditField.Value=strest;
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            loc=drawpoint(app.UIAxes2,'Color','r');%红色标记
            t0=loc.Position(1);%鼠标选取点横坐标
            n=floor(app.F*t0);%起始点序号
            dur=ceil(30*1e-3*app.F);%终止点与起始点间隔
            signal(1:1+dur)=app.SIG(n:n+dur)
            %下面一行代码用来提取幅度谱数据
            [fresult,f1,f2]=estresul(app,signal(1:1+dur),app.F,app.LENT);
            plot(app.UIAxes3,fresult(1:end/2));%画出幅度谱
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.AuthorsnameMenu = uimenu(app.AboutMenu);
            app.AuthorsnameMenu.Text = 'Author''s name:尚尔淦';
            app.studentsIDPB19010390Menu = uimenu(app.AboutMenu);
            app.studentsIDPB19010390Menu.Text = 'student''s ID:PB19010390';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Menu_26 = uimenu(app.HelpMenu);
            app.Menu_26.Text = '预先说明';
            app.Menu_27 = uimenu(app.Menu_26);
            app.Menu_27.Text = '本菜单中序号1,2,3表示功能选项,(1)(2)等表示操作顺序';
            app.DTMFMenu = uimenu(app.HelpMenu);
            app.DTMFMenu.Text = 'DTMF的产生';
            app.Menu = uimenu(app.DTMFMenu);
            app.Menu.Text = '1.拨号';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = '(1).先设置采样频率';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.Text = '(2).再设置采样时间';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.Text = '(3).输入号码或按键结束后点击拨号即可显示波形';
            app.Menu_8 = uimenu(app.Menu);
            app.Menu_8.Text = '(4).号码栏不空时调整时间与频率立刻会显示该时间频率下波形图';
            app.Menu_2 = uimenu(app.DTMFMenu);
            app.Menu_2.Text = '2.外放';
            app.Menu_5 = uimenu(app.Menu_2);
            app.Menu_5.Text = '点击外放立刻显示波形并播放声音';
            app.Menu_3 = uimenu(app.DTMFMenu);
            app.Menu_3.Text = '3.保存';
            app.informationmatMenu = uimenu(app.Menu_3);
            app.informationmatMenu.Text = '点击保存立刻生成文件“information.mat"';
            app.Menu_9 = uimenu(app.DTMFMenu);
            app.Menu_9.Text = '4.回退';
            app.Menu_10 = uimenu(app.Menu_9);
            app.Menu_10.Text = '删除号码栏末尾数字';
            app.Menu_11 = uimenu(app.DTMFMenu);
            app.Menu_11.Text = '5.清空';
            app.Menu_12 = uimenu(app.Menu_11);
            app.Menu_12.Text = '清空号码栏';
            app.DTMFMenu_2 = uimenu(app.HelpMenu);
            app.DTMFMenu_2.Text = 'DTMF的检测';
            app.Menu_13 = uimenu(app.DTMFMenu_2);
            app.Menu_13.Text = '测试程序波形';
            app.Menu_17 = uimenu(app.Menu_13);
            app.Menu_17.Text = '(1).点击即可显示目前程序中存在的波形以及号码';
            app.Menu_18 = uimenu(app.Menu_13);
            app.Menu_18.Text = '(2).在测试结果栏显示系统测试号码结果';
            app.Menu_16 = uimenu(app.DTMFMenu_2);
            app.Menu_16.Text = '上传并测试';
            app.Menu_19 = uimenu(app.Menu_16);
            app.Menu_19.Text = '(1).点击后系统会提示选择测试文件';
            app.Menu_20 = uimenu(app.Menu_16);
            app.Menu_20.Text = '(2).文件选中后在用户信息栏目显示用户文件号码';
            app.Menu_21 = uimenu(app.Menu_16);
            app.Menu_21.Text = '(3).同时在测试结果栏显示系统测试号码结果';
            app.Menu_22 = uimenu(app.DTMFMenu_2);
            app.Menu_22.Text = '幅度谱的显示';
            app.Menu_23 = uimenu(app.Menu_22);
            app.Menu_23.Text = '(1).请先点击测试结果波形的空白处触发十字按键';
            app.Menu_24 = uimenu(app.Menu_22);
            app.Menu_24.Text = '(2).十字按键点击测试结果波形中的任意位置后显示幅度谱';
            app.Menu_25 = uimenu(app.Menu_22);
            app.Menu_25.Text = '(3).若想观察不同位置的幅度谱请重复以上操作';
            app.Menu_14 = uimenu(app.HelpMenu);
            app.Menu_14.Text = '交互区域';
            app.Menu_15 = uimenu(app.Menu_14);
            app.Menu_15.Text = '交互区域会实时显示输入号码的合法性';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'拨号信号'; ''})
            xlabel(app.UIAxes, {'时间 t'; ''})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '测试结果波形')
            xlabel(app.UIAxes2, {' 时间 t'; ''})
            ylabel(app.UIAxes2, {''; ''})
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, {'幅度谱'; ''})
            xlabel(app.UIAxes3, {'频率 f'; ''})
            zlabel(app.UIAxes3, 'Z')
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = {'请输入您的号码'; ''};
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = {'输入采样频率'; ''};
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 8000;
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = {'采样时间'; '（ms)'; ''};
            app.msSlider = uislider(app.UIFigure);
            app.msSlider.Limits = [100 1000];
            app.msSlider.ValueChangedFcn = createCallbackFcn(app, @msSliderValueChanged, true);
            app.msSlider.Value = 100;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = {'*'; ''};
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = {'拨号'; ''};
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = {'外放📢'; ''; ''; ''};
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = {'保存💾'; ''};
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = {'清空'; ''};
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = {'交互区域'; ''};
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = '您好，这里是交互区域';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '测试结果';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '请按下测试按钮';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '测试程序波形';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = {'回退'; ''};
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = {'上传并测试'; ''};
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = {'用户信息'; ''};
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = '请上传用户拨号';
            app.DTMFLabel = uilabel(app.UIFigure);
            app.DTMFLabel.Text = 'DTMF产生区';
            app.DTMFLabel_2 = uilabel(app.UIFigure);
            app.DTMFLabel_2.Text = 'DTMF检测区';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DTMF_FinalHW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end